classdef udp_communication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ExitButton        matlab.ui.control.Button
        CaptureUDPButton  matlab.ui.control.StateButton
        GraphicsPanel     matlab.ui.container.Panel
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data % Description
        time_stamp
        udp_p
        strain_1
        strain_2
        strain_3
        temperature

        line_strain_1
        line_strain_2
        line_strain_3
        line_temperature


    end
    
    methods (Access = private)
        
        function readUDPData(app, src, ~)
            disp("Data received:");
            read_data = read(src,1,"string");
            disp(read_data.Data + newline);
            update_data(app, read_data);
            update_graphics(app);

        end
       
        
        function update_data(app, read_data)
            struct_data = jsondecode(read_data.Data);
            new_data = [struct_data.strain_1; struct_data.strain_2; struct_data.strain_3; struct_data.temp; double(struct_data.timestamp)];
            app.data = [app.data new_data];
            app.strain_1 = [app.strain_1 struct_data.strain_1];
            app.strain_2 = [app.strain_2 struct_data.strain_2];
            app.strain_3 = [app.strain_3 struct_data.strain_3];
            app.temperature = [app.temperature struct_data.temp];
            app.time_stamp = [app.time_stamp uint64(struct_data.timestamp)];
            
        end
        
        function update_graphics(app)
            app.UIAxes.XLim = [app.time_stamp(1) app.time_stamp(end)];
            app.UIAxes2.XLim = [app.time_stamp(1) app.time_stamp(end)];
            app.UIAxes3.XLim = [app.time_stamp(1) app.time_stamp(end)];
            app.UIAxes4.XLim  = [app.time_stamp(1) app.time_stamp(end)];
            app.line_strain_1.XData =  app.time_stamp;
            app.line_strain_1.YData =  app.strain_1;
            app.line_strain_2.XData =  app.time_stamp;
            app.line_strain_2.YData =  app.strain_2;
            app.line_strain_3.XData =  app.time_stamp;
            app.line_strain_3.YData =  app.strain_3;
            app.line_temperature.XData = app.time_stamp;
            app.line_temperature.YData = app.temperature;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.line_strain_1 = line(app.UIAxes3, 0, 0);
            app.line_strain_2 = line(app.UIAxes2, 0, 0);
            app.line_strain_3 = line(app.UIAxes, 0, 0);
            app.line_temperature = line(app.UIAxes4, 0 ,0);
        end

        % Value changed function: CaptureUDPButton
        function CaptureUDPButtonValueChanged(app, event)
            value = app.CaptureUDPButton.Value;
            if value
                app.CaptureUDPButton.Text = 'Stop UDP';
                app.udp_p = udpport("datagram", "LocalHost", "127.0.0.1", "LocalPort", 9870);
                disp('udp connected');
                configureCallback(app.udp_p,"datagram", 1 ,@(src, evnt)readUDPData(app, src, evnt));
            else
                app.CaptureUDPButton.Text = 'Capture UDP';
                disp("off")
                configureCallback(app.udp_p,"off")
                flush(app.udp_p,"output");
                app.udp_p.delete();
                disp('udp disconnected');
                
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            %disconnect udp if exits
            if ~isempty(app.udp_p)
                configureCallback(app.udp_p,"off");
                flush(app.udp_p,"output");
                app.udp_p.delete();
                disp('udp disconnected');
            end
            % Delete UIFigure when app is deleted
            delete(app.UIFigure);
            % Delete UIFigure when app is deleted
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraphicsPanel = uipanel(app.UIFigure);
            app.GraphicsPanel.Title = 'Graphics';
            app.UIAxes2 = uiaxes(app.GraphicsPanel);
            title(app.UIAxes2, 'Strain 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.GraphicsPanel);
            title(app.UIAxes4, 'Temperature')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, '°C')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.GraphicsPanel);
            title(app.UIAxes, 'Strain 3')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GraphicsPanel);
            title(app.UIAxes3, 'Strain 1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.CaptureUDPButton = uibutton(app.UIFigure, 'state');
            app.CaptureUDPButton.ValueChangedFcn = createCallbackFcn(app, @CaptureUDPButtonValueChanged, true);
            app.CaptureUDPButton.Text = 'Capture UDP';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = udp_communication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end